classdef app_one < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowsefolderButton  matlab.ui.control.Button
        ZplaneListBoxLabel  matlab.ui.control.Label
        WellListBox         matlab.ui.control.ListBox
        WellListBoxLabel    matlab.ui.control.Label
        ZplaneListBox       matlab.ui.control.ListBox
        ImageLabel          matlab.ui.control.Label
        Image               matlab.ui.control.Image
        ErrorLabel          matlab.ui.control.Label
        PreviousButton      matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
        RunMLmodelButton    matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        folder_path = '';
        filenames_data = struct;
        filenames_data_sorted = struct;
        img_index = 1;
    end
    
    methods (Access = private)
        
        function img_formatted = format_img(~, img)
           % Image format conversion for display

           % uint16 -> uint8
            if isa(img, 'uint16')
                img_uint8 = uint8(double(img) / double(max(img(:))) * 255);     
            else
                img_uint8 = im2uint8(img); % catch-all for other datatypes
            end
            
            % grayscale -> RGB
            if size(img_uint8, 3) == 1
                img_formatted = repmat(img_uint8, [1 1 3]);
            else
                img_formatted = img_uint8;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            
            % Select folder

            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BSc_thesis');

            if app.folder_path == 0   % User canceled folder selection
                app.ErrorLabel.Text = 'No folder selected.';
                return;
            end
            
            filenames = dir(fullfile(app.folder_path, '*.tif'));
            nr_images = numel(filenames);
            file_paths = cell(nr_images, 1);

            pattern = '(?<WellID>\w+)_(?<ReadIndex>\d+)_(?<ChannelIndex>\d+)_(?<ZIndex>\w+)_(?<ChannelName>[\w\s]+)_(?<CycleIndex>\d+)';
            pattern2 = '(?<WellID>\w+)_(?<ReadIndex>\d+)_(?<ChannelIndex>\d+)_(?<ZIndex>\w+)_(?<ChannelName>[\w\s]+)_(?<CycleIndex>\d+).tif';
            app.filenames_data = struct('FilePath', {}, 'WellID', {}, 'ReadIndex', {}, 'ChannelIndex', {}, 'ZIndex', {}, 'ZPlane', {}, 'ChannelName', {}, 'CycleIndex', {});
            
            % Create main database (filenames_data)- Save path + Extract data from filenames
            
            for n = 1:nr_images
                
                f = filenames(n).name;
                file_data = regexp(f, pattern, 'names');
                
                % viska välja valed tif failid, use continue
                % New fields- FilePath and ZPlane
                file_paths{n} = fullfile(app.folder_path, f);
                file_data.FilePath = file_paths{n};
            
                zindex_str = file_data.ZIndex;
                z_plane = str2double(zindex_str(3));
                file_data.ZPlane = z_plane;
            
                app.filenames_data(n) = file_data;
            
            end

            % Sort filenames_data alphanumerically by WellID

            wellIDs = {app.filenames_data.WellID};
            letters = cell(size(wellIDs));
            numbers = zeros(size(wellIDs));

            for i = 1:length(wellIDs)
                wellID = wellIDs{i};
                letters{i} = wellID(1);
                numbers(i) = str2double(wellID(2:end));
            end

            [~, sorted_idx] = sortrows([cell2mat(letters'), numbers']);  % Sort by letters, then by numbers
            app.filenames_data_sorted = app.filenames_data(sorted_idx);  % Reorder filenames_data based on sorted indices

            % Load in well options
            
            wellIDs = {app.filenames_data_sorted.WellID}; % in correct order
            uniqueIDs = unique(wellIDs, 'stable');
            app.WellListBox.Items = cellstr(string(uniqueIDs));

            % Load in all Zplane options (initially)

            zplane_values = unique(cell2mat({app.filenames_data_sorted.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);

            % Display first well of folder

            image1 = imread(app.filenames_data_sorted(1).FilePath);
            img_formatted = app.format_img(image1);
            app.Image.ImageSource = img_formatted;

            displayname = split(app.filenames_data_sorted(1).FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname;
            app.ErrorLabel.Text = "";
        end

        % Value changed function: WellListBox
        function WellListBoxValueChanged(app, event)
            
            value = app.WellListBox.Value;
            wellIDs = {app.filenames_data_sorted.WellID};
            well_selected = app.filenames_data_sorted(strcmp(wellIDs, value));

            % Load in Zplane options for specified well
            zplane_values = unique(cell2mat({well_selected.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);
            
            % Display first image of specified well
            img = imread(well_selected(1).FilePath);
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(well_selected(1).FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname;
        end

        % Value changed function: ZplaneListBox
        function ZplaneListBoxValueChanged(app, event)

            % Stay on the same well indexes but change Z index
            
            well_value = app.WellListBox.Value;
            wellIDs = {app.filenames_data_sorted.WellID};
            well_selected = app.filenames_data_sorted(strcmp(wellIDs, well_value));

            z_value = app.ZplaneListBox.Value;
            %z_selected = well_selected([well_selected.ZPlane] == z_value);
            
            zplanes = {well_selected.ZPlane};
            z_selected = well_selected(strcmp(zplanes, z_value));

            % Update display

            img = imread(z_selected.FilePath); % get too few or too many inputs?
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(z_selected.FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname; 

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.img_index == numel(app.filenames_data)

                % if user has checked all images
                % next activity

            else
                
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames_data_sorted(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_next = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_next);
    
                app.Image.ImageSource = img_formatted;

            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.img_index == 1

                app.ErrorLabel.Text = "Index = 1, can't go further back";

            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_prev = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_prev);
    
                app.Image.ImageSource = img_formatted;                           
            end
        end

        % Button pushed function: RunMLmodelButton
        function RunMLmodelButtonPushed(app, event)

            % Find any version of Ilastik.exe from user computer
            basePath = 'C:\Program Files\';
            wildcardPath = dir(fullfile(basePath, 'ilastik*'));

            if ~isempty(wildcardPath)
                ilastik_path = fullfile(basePath, wildcardPath(1).name, 'ilastik.exe'); % get full path
            else
                error('Ilastik not found among Program Files.');
            end

            % Later: make choosing Ilastik ML model dynamic
            ilastik_proj_path = 'C:\Users\Kasutaja\Desktop\BSc_thesis\testrun\draft1.ilp';
            cd(app.folder_path); % images folder
            
            % do I exclude TIFF format?
            images = dir(fullfile(app.folder_path, '*.tif')); 
            cmds = struct('command', {});
            
            for i = 1:numel(images)
                cmd = sprintf('"%s" --headless --project="%s" "%s"', ...
                ilastik_path, ...
                ilastik_proj_path, ...
                images(i).name);
                cmds(i).command = cmd;
            end
            
            % Tracking progress + errors
            logFile = 'process_log.txt';
            errorLog = 'error_log.txt';
            
            logs = fopen(logFile, 'a');
            errors = fopen(errorLog, 'a');
            
            if logs == -1
                error('Failed to open process_log file.');
            end

            if errors == -1
                error('Failed to open error_log file.');
            end
            
            cleanup = onCleanup(@() fclose('all')); % close all open files when the script ends
            

            try
                for i = 1:numel(cmds)
                    fprintf(logs, '\n Analyzing image %d/%d: %s', i, numel(cmds), images(i).name);
                    status = system(cmds(i).command); % Run command
                    
                    if status == 0
                        fprintf(logs, ' - Success.\n');
                        % BUG - ütleb success kui on selgelt error, A1_bad.tif file on
                        % saast
                    else
                        fprintf(logs, 'Image %d failed with status %d.\n', i, status);
                        fprintf(errors, 'Image %d failed: %s\n', i, cmds(i).command);
                    end
                end
            
            catch ME
                fprintf(logs, 'Script interrupted with error: %s\n', ME.message);
                fprintf(errors, 'Script interrupted with error: %s\n', ME.message);
                fprintf(errors, 'Error ID: %s\n', ME.identifier);
                
                % save failed things to run again?
                % critical errors - stop the loop?
            
            end
            
            fclose(logs);
            fclose(errors);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunMLmodelButton = uibutton(app.UIFigure, 'push');
            app.RunMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunMLmodelButtonPushed, true);
            app.RunMLmodelButton.Text = 'Run ML model';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'0'};
            app.ZplaneListBox.ValueChangedFcn = createCallbackFcn(app, @ZplaneListBoxValueChanged, true);
            app.ZplaneListBox.Value = '0';
            app.WellListBoxLabel = uilabel(app.UIFigure);
            app.WellListBoxLabel.Text = 'Well';
            app.WellListBox = uilistbox(app.UIFigure);
            app.WellListBox.Items = {'1'};
            app.WellListBox.ValueChangedFcn = createCallbackFcn(app, @WellListBoxValueChanged, true);
            app.WellListBox.Value = '1';
            app.ZplaneListBoxLabel = uilabel(app.UIFigure);
            app.ZplaneListBoxLabel.Text = 'Z-plane';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_one

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end